classdef MSE491Project2_GroupA5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        PlayAudioPanel                  matlab.ui.container.Panel
        FullResetButton                 matlab.ui.control.Button
        StopSoundButton                 matlab.ui.control.Button
        ApplySelectedFilterPlayFilteredAudioButton  matlab.ui.control.Button
        AudioFileSelectionPanel         matlab.ui.container.Panel
        PlaySoundButton                 matlab.ui.control.Button
        RecordingStateEditField         matlab.ui.control.EditField
        RecordingStateLabel             matlab.ui.control.Label
        RecordingTimeEditField          matlab.ui.control.NumericEditField
        RecordingTimeEditField_2Label   matlab.ui.control.Label
        RealtimeCheckBox                matlab.ui.control.CheckBox
        RecordVoiceButton               matlab.ui.control.Button
        RecordYourOwnAudioLabel         matlab.ui.control.Label
        ExampleNameOutputwavorOutputmp4Label  matlab.ui.control.Label
        InputFileSaveNameEditField      matlab.ui.control.EditField
        InputFileSaveNameEditFieldLabel  matlab.ui.control.Label
        SaveAlteredAudioLabel           matlab.ui.control.Label
        SaveAudioFileButton             matlab.ui.control.Button
        LengthofFileInTimesEditField    matlab.ui.control.NumericEditField
        LengthofFileInTimesEditFieldLabel  matlab.ui.control.Label
        UploadAudioInputLabel           matlab.ui.control.Label
        SelectedAudioFileEditField      matlab.ui.control.EditField
        SelectedAudioFileLabel          matlab.ui.control.Label
        UploadAudioFileButton           matlab.ui.control.Button
        FiltersPanel                    matlab.ui.container.Panel
        MovingAveragePointsEditField    matlab.ui.control.NumericEditField
        MovingAveragePointsEditFieldLabel  matlab.ui.control.Label
        WindowTypeButtonGroup           matlab.ui.container.ButtonGroup
        KaiserButton                    matlab.ui.control.RadioButton
        HammingButton                   matlab.ui.control.RadioButton
        BlackmanHarrisButton            matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        FilterMinimumRequirementsPanel  matlab.ui.container.Panel
        BandpassBandstopCutoffsLabel    matlab.ui.control.Label
        LowpassHighpassLabel            matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        CutoffEditField                 matlab.ui.control.NumericEditField
        CutoffEditField_2Label          matlab.ui.control.Label
        Wn1EditField                    matlab.ui.control.NumericEditField
        Wn1EditField_2Label             matlab.ui.control.Label
        Wn2EditField                    matlab.ui.control.NumericEditField
        Wn2EditField_3Label             matlab.ui.control.Label
        FilterTypeButtonGroup           matlab.ui.container.ButtonGroup
        BandStopButton                  matlab.ui.control.ToggleButton
        BandPassButton                  matlab.ui.control.ToggleButton
        HighPassButton                  matlab.ui.control.ToggleButton
        LowPassButton                   matlab.ui.control.ToggleButton
        FilterDesignButtonGroup         matlab.ui.container.ButtonGroup
        WindowedSincButton              matlab.ui.control.ToggleButton
        MovingAvgButton                 matlab.ui.control.ToggleButton
        ChebyshevIButton                matlab.ui.control.ToggleButton
        ButterWorthButton               matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        %global variables
        output
        Fs
        sw
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadAudioFileButton
        function UploadAudioFileButtonPushed(app, event)
            
            %Load filer explorer, user selects file
            [file] = uigetfile({'*.wav';'*.mp3';'*.m4a';'*.*'});
            %display selected file on GUI
            app.SelectedAudioFileEditField.Value = file; 
            %initialize variables and save audio signal and sampling
            %frequency from the selected file
            [app.sw, app.Fs] = audioread(file);
            %Incase of repeated columns/2D audio signal, save as 1
            %column/1D
            app.sw = app.sw(:,1);
            %Initialize global output as input
            app.output = app.sw;
            %display time of audio file on GUI
            app.LengthofFileInTimesEditField.Value = length(app.sw)/app.Fs;
            app.FilterOrderEditField.Value = 1;


        end

        % Button pushed function: SaveAudioFileButton
        function SaveAudioFileButtonPushed(app, event)
            %saves the output file with the effects to your desired name
            %writes it as amplitude vs samples
            fileOut = app.InputFileSaveNameEditField.Value; 
            audiowrite(fileOut,app.output,app.Fs)
                
        end

        % Button pushed function: 
        % ApplySelectedFilterPlayFilteredAudioButton
        function ApplySelectedFilterPlayFilteredAudioButtonPushed(app, event)
            close all;
            n = app.FilterOrderEditField.Value;
                if app.ChebyshevIButton.Value == 1
                    if app.LowPassButton.Value == 1
                        filtertype = 'low';
                        f = app.CutoffEditField.Value;
                    end
                    if app.HighPassButton.Value == 1
                        filtertype = 'high';
                        f = app.CutoffEditField.Value;
                    end
                    if app.BandPassButton.Value == 1
                        filtertype = 'bandpass';
                        f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                    end
                    if app.BandStopButton.Value == 1 
                        filtertype = 'stop';
                        f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                    end

                    [zb,pb,kb] = cheby1(n,3,f/(app.Fs/2),filtertype); %digital
                    [bb,ab] = zp2tf(zb,pb,kb);
                    %Frequency response of the filter
                    figure(1); freqz(bb,ab); title('Filter Frequency Response'); 
                    app.output = filter(bb,ab,app.sw);
                end
                
                if app.ButterWorthButton.Value == 1
                    if app.LowPassButton.Value == 1
                        filtertype = 'low';
                        f = app.CutoffEditField.Value;
                    end
                    if app.HighPassButton.Value == 1
                        filtertype = 'high';
                        f = app.CutoffEditField.Value;
                    end
                    if app.BandPassButton.Value == 1
                        filtertype = 'bandpass';
                        f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                    end
                    if app.BandStopButton.Value == 1 
                        filtertype = 'stop';
                        f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                    end

                
                    [zb,pb,kb] = butter(n,f/(app.Fs/2),filtertype); %digital
                    [bb,ab] = zp2tf(zb,pb,kb);
                    %Frequency response of the filter
                    figure(1); freqz(bb,ab); title('Filter Frequency Response'); 
                    app.output = filter(bb,ab,app.sw);
                end
           
                if app.MovingAvgButton.Value == 1
                    a = app.MovingAveragePointsEditField.Value;
                    even = rem(a,2);
                    
                    if even == 0
                        a = a+1;
                    end

                    if app.LowPassButton.Value == 1
                        %||string(app.FilterTypeDropDown_3.Value) == 'stop'||string(app.FilterTypeDropDown_3.Value) == 'bandpass'
                        t2 = ones(a,1);
                        num1 = t2/a;
                        den1 = [1];
                        figure(1); freqz(num1,den1); title('Filter Frequency Response'); 
                        app.output = filter(num1,den1,app.sw);
                    end

                    if app.HighPassButton.Value == 1
                        %||string(app.FilterTypeDropDown_3.Value) == 'stop'||string(app.FilterTypeDropDown_3.Value) == 'bandpass'
                        t2 = ones(a,1);
                        num2 = t2/a;
                        num2 = -num2;
                        num2(((a-1)/2)+1,1) = 1-(1/a);
                        den2 = [1];
                        figure(1); freqz(num2,den2); title('Filter Frequency Response'); 
                        app.output = filter(num2,den2,app.sw);
                    end

                    if app.BandPassButton.Value == 1
                        %low pass
                        t2 = ones(a,1);
                        num1 = t2/a;
                        %high pass
                        num2 = t2/a;
                        num2 = -num2;
                        num2(((a-1)/2)+1,1) = 1-(1/a);
                        %bandpass
                        num3 = conv(num1,num2);
                        den3 = [1];
                        figure(1); freqz(num3,den3); title('Filter Frequency Response'); 
                        app.output = filter(num3,den3,app.sw);
                    end

                    if app.BandStopButton.Value == 1
                        %low pass
                        t2 = ones(a,1);
                        num1 = t2/a;
                        %high pass
                        num2 = t2/a;
                        num2 = -num2;
                        num2(((a-1)/2)+1,1) = 1-(1/a);
                        %bandstop
                        num4 = num1+num2;
                        den4 = [1];
                        figure(1); freqz(num4,den4); title('Filter Frequency Response'); 
                        app.output = filter(num4,den4,app.sw);
                    end

                end

                if app.WindowedSincButton.Value == 1
                    if app.LowPassButton.Value == 1
                        filtertype = 'low';
                        f = app.CutoffEditField.Value;
                    end
                    if app.HighPassButton.Value == 1
                        filtertype = 'high';
                        f = app.CutoffEditField.Value;
                    end
                    if app.BandPassButton.Value == 1
                        filtertype = 'bandpass';
                        f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                    end
                    if app.BandStopButton.Value == 1 
                        filtertype = 'stop';
                        f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                    end
    
                    even = rem(n,2);
                    if even ~= 0
                        n = n+1;
                    end
    
                    if app.HammingButton.Value == 1
                        bhi = fir1(n,f/(app.Fs/2),filtertype);
                        figure(1); freqz(bhi,1); title('Filter Frequency Response'); 
                        app.output = filter(bhi,1,app.sw);
                    end
    
                    if app.BlackmanHarrisButton.Value == 1
                        bhi = fir1(n,f/(app.Fs/2),filtertype,blackmanharris(n+1));
                        figure(1); freqz(bhi,1); title('Filter Frequency Response'); 
                        app.output = filter(bhi,1,app.sw);
                    end
    
                    if app.RectangularButton.Value == 1
                        bhi = fir1(n,f/(app.Fs/2),filtertype,rectwin(n+1));
                        figure(1); freqz(bhi,1); title('Filter Frequency Response'); 
                        app.output = filter(bhi,1,app.sw);
                    end
    
                    if app.KaiserButton.Value == 1
                        bhi = fir1(n,f/(app.Fs/2),filtertype,kaiser(n+1,2.5)); %beta = 2.5
                        figure(1); freqz(bhi,1); title('Filter Frequency Response'); 
                        app.output = filter(bhi,1,app.sw);
                    end

                end

            sound(app.output,app.Fs);
            

            % INPUT
            figure(2)
            %plot audio signal with respect to time
            t = [0:length(app.sw)-1].*(1./app.Fs); 
            t = t(:); %changes time to only have 1 column
            plot(t,app.sw);
            xlabel('Time (sec)'); 
            ylabel('Amplitude');
            title('Input Audio Signal');

            N = length(app.sw);
            inputFFT = customFFT(app.sw);
            MagInput = abs(inputFFT);
            PhaseInput = angle(inputFFT); 

            figure(3)
            subplot(2,1,1)
            plot(MagInput*2/N); 
            xlabel('Frequency'); 
            ylabel('Magnitude');
            title('Input Double-Sided FFT');

            subplot(2,1,2)
            plot(PhaseInput);
            xlabel("Frequency");
            ylabel("Phase");
            title('Input Phase Response');
      
           
            % OUTPUT
            t = [0:length(app.output)-1].*(1./app.Fs); 
            t = t(:); %changes time to only have 1 column
            
            figure(4)
            plot(t,app.output);
            xlabel('Time (sec)'); 
            ylabel('Amplitude');
            title('Output Audio Signal');

            outputFFT = customFFT(app.output);
            MagOutput = abs(outputFFT);
            PhaseOutput = angle(outputFFT); 

            figure(5)
            subplot(2,1,1)
            plot(MagOutput*2/N); 
            xlabel('Frequency'); 
            ylabel('Magnitude');
            title('Output Double-Sided FFT');

            subplot(2,1,2)
            plot(PhaseOutput);
            xlabel("Frequency");
            ylabel("Phase");
            title('Output Phase Response');
              

            % fft operation without using inbuilt function 
            function [out] = customFFT(x)
                %Take 1 Channel
                x = x(:,1);
                
                % Transpose Signal
                x = x';
                % length of the signal
                N = length(x);
                M = log2(N);
                % adding zeros 
                if (rem(M,1) ~=0)
                    re = rem(M,1);
                    M = M-re+1;
                    Ne = 2^M;
                    x = [x,zeros(1,Ne-N)];
                else
                    Ne = N;
                end
                N = Ne;
                % number of stages
                M = log2(N);
                % bitreversing
                x = bitrevorder(x);
                % code 
                for l =1:M
                    k = 2^l;
                    w = exp((-1j*2*pi*(0:k-1))./(k));
                    for t=0:k:N-1 
                        z(t+1:t+k) = [x(t+1:t+k/2)+x(t+k/2+1:t+k) .* w(1:k/2) , x(t+1:t+k/2)+x(t+k/2+1:t+k).*w(k/2+1:k)] ;            
                    end
                    x = z;
                    z = 0;         
                end
                out = x; 
            end
        end

        % Button pushed function: FullResetButton
        function FullResetButtonPushed(app, event)
            
            %Reset all values to initialized conditions
            clear sound;
            set(app.SelectedAudioFileEditField,'value','');
            app.sw = 0;
            set(app.CutoffEditField,'value',1);
            set(app.Wn1EditField,'value',0);
            set(app.Wn2EditField,'value',0);
            set(app.FilterOrderEditField,'value',1);
            set(app.MovingAveragePointsEditField,'value',0);
            set(app.InputFileSaveNameEditField,'value','');


        end

        % Button pushed function: RecordVoiceButton
        function RecordVoiceButtonPushed(app, event)
            t = app.RecordingTimeEditField.Value;
            app.Fs = 44100 ; 
            nBits = 16 ; 
            nChannels = 2 ; 
            ID = -1; % default audio input device 
            recObj = audiorecorder(app.Fs,nBits,nChannels,ID);
            if app.RealtimeCheckBox.Value == 1
                
                recording = t;
                iteration = 1;
            
                app.sw = [];
                app.output = [];
                
                if app.FilterOrderEditField.Value > 0 && app.CutoffEditField.Value > 0
                    app.RecordingStateEditField.Value = 'Active';
                    while recording > 0
                        
                        recordblocking(recObj, 1);
                        x = getaudiodata(recObj);
                        app.sw(app.Fs*(iteration-1)+1:app.Fs*(iteration)+1) = x(1:app.Fs+1);
                        figure(1);
                        plot(app.sw);
                        xlabel('Sample Number'); 
                        ylabel('Amplitude');
                        title('Output Audio Signal');
                        
                        %sound(x,app.Fs);
                        
                        iteration = iteration + 1;
                        recording = recording - 1;
                        n = app.FilterOrderEditField.Value;
                        
                        if app.ChebyshevIButton.Value == 1
                            if app.LowPassButton.Value == 1
                                filtertype = 'low';
                                f = app.CutoffEditField.Value;
                            end
                            if app.HighPassButton.Value == 1
                                filtertype = 'high';
                                f = app.CutoffEditField.Value;
                            end
                            if app.BandPassButton.Value == 1
                                filtertype = 'bandpass';
                                f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                            end
                            if app.BandStopButton.Value == 1 
                                filtertype = 'stop';
                                f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                            end
        
                            [zb,pb,kb] = cheby1(n,3,f/(app.Fs/2),filtertype); %digital
                            [bb,ab] = zp2tf(zb,pb,kb);
                            %Frequency responce of the filter
                            figure(3); freqz(bb,ab); title('Filter Frequency Response'); 
                            y = filter(bb,ab,x);
                        end
                        
                        if app.ButterWorthButton.Value == 1
                            if app.LowPassButton.Value == 1
                                filtertype = 'low';
                                f = app.CutoffEditField.Value;
                            end
                            if app.HighPassButton.Value == 1
                                filtertype = 'high';
                                f = app.CutoffEditField.Value;
                            end
                            if app.BandPassButton.Value == 1
                                filtertype = 'bandpass';
                                f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                            end
                            if app.BandStopButton.Value == 1 
                                filtertype = 'stop';
                                f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                            end
        
                        
                            [zb,pb,kb] = butter(n,f/(app.Fs/2),filtertype); %digital
                            [bb,ab] = zp2tf(zb,pb,kb);
                            %Frequency responce of the filter
                            figure(3); freqz(bb,ab); title('Filter Frequency Response');
                            y = filter(bb,ab,x);
                        end
                   
                        if app.MovingAvgButton.Value == 1
                        a = app.MovingAveragePointsEditField.Value;
                        even = rem(a,2);
                            if even == 0
                            a = a+1;
                            end
        
                            if app.LowPassButton.Value == 1
                             %||string(app.FilterTypeDropDown_3.Value) == 'stop'||string(app.FilterTypeDropDown_3.Value) == 'bandpass'
                             t2 = ones(a,1);
                             num1 = t2/a;
                             den1 = [1];
                             figure(3); freqz(num1,den1); title('Filter Frequency Response');
                             y = filter(num1,den1,x);
                             end
        
                            if app.HighPassButton.Value == 1
                                %||string(app.FilterTypeDropDown_3.Value) == 'stop'||string(app.FilterTypeDropDown_3.Value) == 'bandpass'
                                t2 = ones(a,1);
                                num2 = t2/a;
                                num2 = -num2;
                                num2(((a-1)/2)+1,1) = 1-(1/a);
                                den2 = [1];
                                figure(3); freqz(num2,den2); title('Filter Frequency Response');
                                y = filter(num2,den2,x);
                            end
        
                            if app.BandPassButton.Value == 1
                                %low pass
                                t2 = ones(a,1);
                                num1 = t2/a;
                                %high pass
                                num2 = t2/a;
                                num2 = -num2;
                                num2(((a-1)/2)+1,1) = 1-(1/a);
                                %bandpass
                                num3 = conv(num1,num2);
                                den3 = [1];
                                figure(3); freqz(num3,den3); title('Filter Frequency Response');
                                y = filter(num3,den3,x);
                            end
        
                            if app.BandStopButton.Value == 1
                                %low pass
                                t2 = ones(a,1);
                                num1 = t2/a;
                                %high pass
                                num2 = t2/a;
                                num2 = -num2;
                                num2(((a-1)/2)+1,1) = 1-(1/a);
                                %bandstop
                                num4 = num1+num2;
                                den4 = [1];
                                figure(3); freqz(num4,den4); title('Filter Frequency Response');
                                y = filter(num4,den4,x);
                            end
                            
        
                        end
        
                        if app.WindowedSincButton.Value == 1
                            if app.LowPassButton.Value == 1
                                filtertype = 'low';
                                f = app.CutoffEditField.Value;
                            end
                            if app.HighPassButton.Value == 1
                                filtertype = 'high';
                                f = app.CutoffEditField.Value;
                            end
                            if app.BandPassButton.Value == 1
                                filtertype = 'bandpass';
                                f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                            end
                            if app.BandStopButton.Value == 1 
                                filtertype = 'stop';
                                f = [app.Wn1EditField.Value app.Wn2EditField.Value];
                            end
            
                            even = rem(n,2);
                            if even ~= 0
                                n = n+1;
                            end
            
                            if app.HammingButton.Value == 1
                                bhi = fir1(n,f/(app.Fs/2),filtertype);
                                figure(3); freqz(bhi,1); title('Filter Frequency Response');
                                y = filter(bhi,1,x);
                            end
            
                            if app.BlackmanHarrisButton.Value == 1
                                bhi = fir1(n,f/(app.Fs/2),filtertype,blackmanharris(n+1));
                                figure(3); freqz(bhi,1); title('Filter Frequency Response');
                                y= filter(bhi,1,x);
                            end
            
                            if app.RectangularButton.Value == 1
                                bhi = fir1(n,f/(app.Fs/2),filtertype,rectwin(n+1));
                                figure(3); freqz(bhi,1); title('Filter Frequency Response');
                                y = filter(bhi,1,x);
                            end
            
                            if app.KaiserButton.Value == 1
                                bhi = fir1(n,f/(app.Fs/2),filtertype,kaiser(n+1,2.5));
                                figure(3); freqz(bhi,1); title('Filter Frequency Response');
                                y= filter(bhi,1,x);
                            end
    
            
                        end
                        app.output(app.Fs*(iteration-1)+1:app.Fs*(iteration)+1) = y(1:app.Fs+1);
                        %sound(app.output, app.Fs);
                        figure(2);
                        plot(app.output);
                        xlabel('Sample Number'); 
                        ylabel('Amplitude');
                        title('Output Audio Signal');
                    end


                    app.sw = app.sw(:);
                    N = length(app.sw);
                    inputFFT = customFFT(app.sw);
                    MagInput = abs(inputFFT);
                    PhaseInput = angle(inputFFT); 
        
                    figure(3)
                    subplot(2,1,1)
                    plot(MagInput*2/N); 
                    xlabel('Frequency'); 
                    ylabel('Magnitude');
                    title('Input Double-Sided FFT');
        
                    subplot(2,1,2)
                    plot(PhaseInput);
                    xlabel("Frequency");
                    ylabel("Phase");
                    title('Input Phase Response');
              
                   
                    % OUTPUT   
                    app.output = app.output(:);
                    outputFFT = customFFT(app.output);
                    MagOutput = abs(outputFFT);
                    PhaseOutput = angle(outputFFT); 
        
                    figure(5)
                    subplot(2,1,1)
                    plot(MagOutput*2/N); 
                    xlabel('Frequency'); 
                    ylabel('Magnitude');
                    title('Output Double-Sided FFT');
        
                    subplot(2,1,2)
                    plot(PhaseOutput);
                    xlabel("Frequency");
                    ylabel("Phase");
                    title('Output Phase Response');
                  
        
                    app.RecordingStateEditField.Value = 'Inactive';
                end
            %sound(app.output, app.Fs);
            else
                if t > 0
                    app.RecordingStateEditField.Value = 'Active';
                    recordblocking(recObj, t);
                    app.RecordingStateEditField.Value = 'Inactive';
                    app.sw = getaudiodata(recObj);
                    app.output = app.sw;
                    %sound(app.output, app.Fs);
                end
            end

        % fft operation without using inbuilt function 
        function [out] = customFFT(x)
            %Take 1 Channel
            x = x(:,1);
            
            % Transpose Signal
            x = x';
            % length of the signal
            N = length(x);
            M = log2(N);
            % adding zeros 
            if (rem(M,1) ~=0)
                re = rem(M,1);
                M = M-re+1;
                Ne = 2^M;
                x = [x,zeros(1,Ne-N)];
            else
                Ne = N;
            end
            N = Ne;
            % number of stages
            M = log2(N);
            % bitreversing
            x = bitrevorder(x);
            % code 
            for l =1:M
                k = 2^l;
                w = exp((-1j*2*pi*(0:k-1))./(k));
                for t=0:k:N-1 
                    z(t+1:t+k) = [x(t+1:t+k/2)+x(t+k/2+1:t+k) .* w(1:k/2) , x(t+1:t+k/2)+x(t+k/2+1:t+k).*w(k/2+1:k)] ;            
                end
                x = z;
                z = 0;         
            end
            out = x; 
        end


        end

        % Button pushed function: StopSoundButton
        function StopSoundButtonPushed(app, event)
            
            clear sound;

        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            sound(app.output,app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FiltersPanel = uipanel(app.UIFigure);
            app.FiltersPanel.Title = 'Filters';
            app.FilterDesignButtonGroup = uibuttongroup(app.FiltersPanel);
            app.FilterDesignButtonGroup.Title = 'Filter Design';
            app.ButterWorthButton = uitogglebutton(app.FilterDesignButtonGroup);
            app.ButterWorthButton.Text = 'ButterrWorth';
            app.ButterWorthButton.Value = true;
            app.ChebyshevIButton = uitogglebutton(app.FilterDesignButtonGroup);
            app.ChebyshevIButton.Text = 'Chebyshev I';
            app.MovingAvgButton = uitogglebutton(app.FilterDesignButtonGroup);
            app.MovingAvgButton.Text = 'Moving Avg';
            app.WindowedSincButton = uitogglebutton(app.FilterDesignButtonGroup);
            app.WindowedSincButton.Text = 'Windowed-Sinc';
            app.FilterTypeButtonGroup = uibuttongroup(app.FiltersPanel);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowPassButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.LowPassButton.Text = 'LowPass';
            app.LowPassButton.Value = true;
            app.HighPassButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.HighPassButton.Text = 'HighPass';
            app.BandPassButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.BandPassButton.Text = 'BandPass';
            app.BandStopButton = uitogglebutton(app.FilterTypeButtonGroup);
            app.BandStopButton.Text = 'BandStop';
            app.FilterMinimumRequirementsPanel = uipanel(app.FiltersPanel);
            app.FilterMinimumRequirementsPanel.Title = 'Filter Minimum Requirements';
            app.Wn2EditField_3Label = uilabel(app.FilterMinimumRequirementsPanel);
            app.Wn2EditField_3Label.Text = 'Wn2';
            app.Wn2EditField = uieditfield(app.FilterMinimumRequirementsPanel, 'numeric');
            app.Wn1EditField_2Label = uilabel(app.FilterMinimumRequirementsPanel);
            app.Wn1EditField_2Label.Text = 'Wn1';
            app.Wn1EditField = uieditfield(app.FilterMinimumRequirementsPanel, 'numeric');
            app.CutoffEditField_2Label = uilabel(app.FilterMinimumRequirementsPanel);
            app.CutoffEditField_2Label.Text = 'Cut-off ';
            app.CutoffEditField = uieditfield(app.FilterMinimumRequirementsPanel, 'numeric');
            app.FilterOrderEditFieldLabel = uilabel(app.FilterMinimumRequirementsPanel);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FilterMinimumRequirementsPanel, 'numeric');
            app.LowpassHighpassLabel = uilabel(app.FilterMinimumRequirementsPanel);
            app.LowpassHighpassLabel.Text = 'Lowpass & Highpass';
            app.BandpassBandstopCutoffsLabel = uilabel(app.FilterMinimumRequirementsPanel);
            app.BandpassBandstopCutoffsLabel.Text = 'Bandpass & Bandstop Cut-offs';
            app.WindowTypeButtonGroup = uibuttongroup(app.FiltersPanel);
            app.WindowTypeButtonGroup.Title = 'Window Type';
            app.RectangularButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.BlackmanHarrisButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.BlackmanHarrisButton.Text = 'Blackman Harris';
            app.HammingButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.KaiserButton = uiradiobutton(app.WindowTypeButtonGroup);
            app.KaiserButton.Text = 'Kaiser';
            app.MovingAveragePointsEditFieldLabel = uilabel(app.FiltersPanel);
            app.MovingAveragePointsEditFieldLabel.Text = {'Moving'; 'Average'; 'Points'};
            app.MovingAveragePointsEditField = uieditfield(app.FiltersPanel, 'numeric');
            app.AudioFileSelectionPanel = uipanel(app.UIFigure);
            app.AudioFileSelectionPanel.Title = 'Audio/File Selection';
            app.UploadAudioFileButton = uibutton(app.AudioFileSelectionPanel, 'push');
            app.UploadAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAudioFileButtonPushed, true);
            app.UploadAudioFileButton.Text = 'Upload Audio File';
            app.SelectedAudioFileLabel = uilabel(app.AudioFileSelectionPanel);
            app.SelectedAudioFileLabel.Text = {'Selected'; 'Audio File:'};
            app.SelectedAudioFileEditField = uieditfield(app.AudioFileSelectionPanel, 'text');
            app.SelectedAudioFileEditField.Editable = 'off';
            app.UploadAudioInputLabel = uilabel(app.AudioFileSelectionPanel);
            app.UploadAudioInputLabel.Text = 'Upload Audio Input';
            app.LengthofFileInTimesEditFieldLabel = uilabel(app.AudioFileSelectionPanel);
            app.LengthofFileInTimesEditFieldLabel.Text = {'Length of File'; 'In Time (s)'};
            app.LengthofFileInTimesEditField = uieditfield(app.AudioFileSelectionPanel, 'numeric');
            app.LengthofFileInTimesEditField.Editable = 'off';
            app.SaveAudioFileButton = uibutton(app.AudioFileSelectionPanel, 'push');
            app.SaveAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioFileButtonPushed, true);
            app.SaveAudioFileButton.Text = 'Save Audio File';
            app.SaveAlteredAudioLabel = uilabel(app.AudioFileSelectionPanel);
            app.SaveAlteredAudioLabel.Text = 'Save Altered Audio';
            app.InputFileSaveNameEditFieldLabel = uilabel(app.AudioFileSelectionPanel);
            app.InputFileSaveNameEditFieldLabel.Text = {'Input File'; 'Save Name'};
            app.InputFileSaveNameEditField = uieditfield(app.AudioFileSelectionPanel, 'text');
            app.ExampleNameOutputwavorOutputmp4Label = uilabel(app.AudioFileSelectionPanel);
            app.ExampleNameOutputwavorOutputmp4Label.Text = 'Example Name: Output.wav or Output.mp4';
            app.RecordYourOwnAudioLabel = uilabel(app.AudioFileSelectionPanel);
            app.RecordYourOwnAudioLabel.Text = 'Record Your Own Audio';
            app.RecordVoiceButton = uibutton(app.AudioFileSelectionPanel, 'push');
            app.RecordVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @RecordVoiceButtonPushed, true);
            app.RecordVoiceButton.Text = 'Record Voice';
            app.RealtimeCheckBox = uicheckbox(app.AudioFileSelectionPanel);
            app.RealtimeCheckBox.Text = 'Real-time';
            app.RecordingTimeEditField_2Label = uilabel(app.AudioFileSelectionPanel);
            app.RecordingTimeEditField_2Label.Text = 'Recording Time';
            app.RecordingTimeEditField = uieditfield(app.AudioFileSelectionPanel, 'numeric');
            app.RecordingStateLabel = uilabel(app.AudioFileSelectionPanel);
            app.RecordingStateLabel.Text = 'Recording State';
            app.RecordingStateEditField = uieditfield(app.AudioFileSelectionPanel, 'text');
            app.RecordingStateEditField.Editable = 'off';
            app.PlaySoundButton = uibutton(app.AudioFileSelectionPanel, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.PlayAudioPanel = uipanel(app.UIFigure);
            app.PlayAudioPanel.Title = 'Play Audio';
            app.ApplySelectedFilterPlayFilteredAudioButton = uibutton(app.PlayAudioPanel, 'push');
            app.ApplySelectedFilterPlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySelectedFilterPlayFilteredAudioButtonPushed, true);
            app.ApplySelectedFilterPlayFilteredAudioButton.Text = {'Apply Selected Filter'; '&'; 'Play Filtered Audio'};
            app.StopSoundButton = uibutton(app.PlayAudioPanel, 'push');
            app.StopSoundButton.ButtonPushedFcn = createCallbackFcn(app, @StopSoundButtonPushed, true);
            app.StopSoundButton.Text = 'Stop Sound';
            app.FullResetButton = uibutton(app.PlayAudioPanel, 'push');
            app.FullResetButton.ButtonPushedFcn = createCallbackFcn(app, @FullResetButtonPushed, true);
            app.FullResetButton.Text = 'Full Reset';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Capture.PNG');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSE491Project2_GroupA5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end